classdef ISETBioCSFGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainView                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        visualfieldparamsPanel          matlab.ui.container.Panel
        radialeccentricitydegsSliderLabel  matlab.ui.control.Label
        radialeccentricitydegsSlider    matlab.ui.control.Slider
        polareccentricitydegsSlider     matlab.ui.control.Knob
        fieldofviewdegsSliderLabel      matlab.ui.control.Label
        fieldofviewdegsSlider           matlab.ui.control.Slider
        Switch_2                        matlab.ui.control.Switch
        visualFieldView                 matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        stimulationthresholdparamsPanel  matlab.ui.container.Panel
        sf_noLabel                      matlab.ui.control.Label
        sf_noSpinner                    matlab.ui.control.Spinner
        sf_maxLabel                     matlab.ui.control.Label
        sf_maxSpinner                   matlab.ui.control.Spinner
        sf_minLabel                     matlab.ui.control.Label
        sf_minSpinner                   matlab.ui.control.Spinner
        slope_minSpinnerLabel           matlab.ui.control.Label
        slope_minSpinner                matlab.ui.control.Spinner
        slope_maxSpinnerLabel           matlab.ui.control.Label
        slope_maxSpinner                matlab.ui.control.Spinner
        traintrialsSpinnerLabel         matlab.ui.control.Label
        traintrialsSpinner              matlab.ui.control.Spinner
        testtrialsSpinnerLabel          matlab.ui.control.Label
        testtrialsSpinner               matlab.ui.control.Spinner
        csfView                         matlab.ui.control.UIAxes
        psychometricFunctionView        matlab.ui.control.UIAxes
        conemosaicparamsPanel           matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        eccvaryingmacularpigmentCheckBox  matlab.ui.control.CheckBox
        eccvaryingaperturelightgatheringCheckBox  matlab.ui.control.CheckBox
        eccvaryingapertureblurCheckBox  matlab.ui.control.CheckBox
        fixationaleyemovementsCheckBox  matlab.ui.control.CheckBox
        Switch                          matlab.ui.control.Switch
        integrationtimemsecSpinnerLabel  matlab.ui.control.Label
        integrationtimemsecSpinner      matlab.ui.control.Spinner
        mosaicView                      matlab.ui.control.UIAxes
        opticalparamsPanel              matlab.ui.container.Panel
        conesSwitch                     matlab.ui.control.Switch
        PolanssubjectLabel              matlab.ui.control.Label
        PolanssubjectSpinner            matlab.ui.control.Spinner
        eccvaryingopticalblurxygridCheckBox  matlab.ui.control.CheckBox
        xsamplesSpinnerLabel            matlab.ui.control.Label
        xsamplesSpinner                 matlab.ui.control.Spinner
        ysamplesSpinnerLabel            matlab.ui.control.Label
        ysamplesSpinner                 matlab.ui.control.Spinner
        pupildiametermmLabel            matlab.ui.control.Label
        pupildiametermmSpinner          matlab.ui.control.Spinner
        psfView                         matlab.ui.control.UIAxes
        stimulusparamsPanel             matlab.ui.container.Panel
        cLSpinnerLabel                  matlab.ui.control.Label
        cMSpinner                       matlab.ui.control.Spinner
        cSSpinner                       matlab.ui.control.Spinner
        cLSpinner                       matlab.ui.control.Spinner
        cMSpinnerLabel                  matlab.ui.control.Label
        cSLabel                         matlab.ui.control.Label
        w_minSpinner                    matlab.ui.control.Spinner
        w_maxSpinner                    matlab.ui.control.Spinner
        w_noSpinner                     matlab.ui.control.Spinner
        w_minSpinnerLabel               matlab.ui.control.Label
        w_maxSpinnerLabel               matlab.ui.control.Label
        w_noSpinnerLabel                matlab.ui.control.Label
        stimulusdurationmsSpinnerLabel  matlab.ui.control.Label
        stimulusdurationmsSpinner       matlab.ui.control.Spinner
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        
        % Constants
        maxEcc = 25.0;                           % Maximum mosaic eccentricity
        
        % User settable simulation parameters (mosaic)
        radialEccentricityDegs = 0;              % Continuously-updated radial eccentricity
        polarEccentricityDegs = 0;               % Continuously-updated polar eccentricity
        mosaicSizeDegs = 0;                      % Size of the cone mosaic, in degrees
        
        % User settable simulation parameters (optics)
        PolansSubjectID = 8;
        visualizedWavelength = 550;
        pupilDiameterMM = 3.0;
        
        % Computed
        mosaicEccentricityDegs;                  % Eccentricity of the cone mosaic, computed
        components = struct();                   % Struct containing simulation components, eg. coneMosaic
        mosaicOnVisualFieldPlotHandle;           % The mosaic on visual field plot handle
        
    end
    
     
    methods (Access = private)
        
        % Method called from the startup method as soon as the GUI is
        % presented. Used to initialize the simulation params
        function initializeSimulationParams(app)
            app.readRadialEccentricityDegsSlider(app.radialeccentricitydegsSlider.Value); 
            app.readPolarEccentricityDegsSlider(app.polareccentricitydegsSlider.Value);          
            app.readSizeDegsSlider(app.fieldofviewdegsSlider.Value);
        end
        
        % Method called from the startup method as soon as the GUI is
        % presented. Used to set slider values etc.
        function initializeGUIcomponents(app)
            app.updateRadialEccentricitySliderText();
            app.updateMosaicSizeSliderText();
            app.initializePolarEccentricitySliderTicks();
            drawnow;
        end
        
        % Method called from the startup method as soon as the GUI is
        % presented. Used to initialize all views.
        function initializeViews(app)
            app.initializeVisualFieldView();
            app.initializeMosaicView();
            app.initializePSFView();
            app.initializeCSFView();
            app.updateVisualFieldView();
            drawnow;
        end
        
        function initializeMosaicView(app)
            box(app.mosaicView, 'on');
            set(app.mosaicView, 'XLim', [-0.1 0.1], 'YLim', [-0.1 0.1], ...
                'XTick', [-0.1 0 0.1], 'YTick', [-0.1 0 0.1])
        end
        
        function initializePSFView(app)
            spatialSupportArcMinRange = 5;
            set(app.psfView, 'XLim', spatialSupportArcMinRange*[-0.5 0.5], 'YLim', spatialSupportArcMinRange*[-0.5 0.5], ...
                'XTick', -10:1:10, 'YTick', -10:1:10, 'FontSize', 14);
            box(app.psfView, 'on');
            grid(app.psfView, 'on');
            xlabel(app.psfView, 'arc min.');
            ylabel(app.psfView, 'arc min.');
        end
        
        function initializeCSFView(app)
            set(app.csfView, 'XLim', [0.3 50], 'YLim', [1 1000], ...
                'XTick', [0.3 1 3 10 30], 'YTick', [1 3 10 30 100 300 1000], ...
                'XLim', [0.2 60], 'YLim', [1 1000], 'XScale', 'log', 'YScale', 'log', 'FontSize', 14);
            box(app.csfView, 'on');
            grid(app.csfView, 'on');
            xlabel(app.csfView, 'spatial frequency (c/deg)');
            ylabel(app.csfView, 'contrast sensitivity');
        end
        
        function initializeVisualFieldView(app)
            plot(app.visualFieldView, [-32 32], [0 0], 'k-', 'LineWidth', 1.5, 'Color', [0.5 0.5 0.5]);
            hold(app.visualFieldView, 'on');
            plot(app.visualFieldView, [0 0], [-32 32], 'k-', 'LineWidth', 1.5, 'Color', [0.5 0.5 0.5]);
            for radius = [0.15 0.3 0.6 1.2 2.5 5 10 20]
                logRadius = app.maxEcc*(1+log10(radius))/(1+log10(app.maxEcc));
                plot(app.visualFieldView, logRadius*cosd(0:5:360), logRadius*sind(0:5:360), 'k-', 'Color', [0.5 0.5 0.5], 'LineWidth', 1.0);
            end
            app.mosaicOnVisualFieldPlotHandle = scatter(app.visualFieldView, 0, 0, 14*14, 'ro', 'MarkerFaceColor', [1 0.5 0.5], 'MarkerFaceAlpha', 0.5);
            hold(app.visualFieldView, 'off');
            set(app.visualFieldView, 'XTick', 0, 'YTick', 0, 'XLim', app.maxEcc*[-1 1], 'YLim', app.maxEcc*[-1 1], 'FontSize', 14);
            set(app.visualFieldView, 'Color', 'none', 'XColor', 'none', 'YColor', 'none');
            set(app.visualFieldView, 'XTickLabel', '0');
            set(app.visualFieldView, 'YTickLabel', '0');
            box(app.visualFieldView, 'off');
            xlabel(app.visualFieldView, 'x (degs)');
            ylabel(app.visualFieldView, 'y (degs)');
        end
        
        function initializeSimulationComponents(app)
            % Start the parpool
            dialogBox = uiprogressdlg(app.mainView,'Title','Please Wait',...
                'Message','Starting the parallel pool');
            dialogBox.Value = 0.2; 
            pause(.01)
            poolobj = gcp('nocreate'); 
            if isempty(poolobj)
                parpool('local')
            end
            
            % Regenerate the cone mosaic
            app.regenerateMosaic(dialogBox, 0.5, 1.0);
            
            % Close dialog box
            close(dialogBox);
        end
        
        % Method to regenerate the cone mosaic and update the current
        % progress bar
        function regenerateMosaic(app, dialogBox, currentProgressValue, nextProgressValue)
            
            % Create progress bar
            if (currentProgressValue == 0) 
                dialogBox = uiprogressdlg(app.mainView,'Title','Please Wait',...
                'Message','');
                pause(.01)
            end
            
            % Update progress bar
            deltaProgressValue = (nextProgressValue-currentProgressValue)/2;
            dialogBox.Value = currentProgressValue+deltaProgressValue; 
            dialogBox.Message = 'Generating cone mosaic';
            pause(.01)
            
            % Generate new cone mosaic
            app.components.coneMosaic = cMosaic(...
                'sizeDegs', app.mosaicSizeDegs*[1 1], ... 
                'eccentricityDegs', app.mosaicEccentricityDegs ...  
            );
            
            % Update progress bar
            dialogBox.Value = currentProgressValue+1.5*deltaProgressValue; 
            dialogBox.Message = 'Generating optics';
            pause(.01)
            
            % Generate optics appropriate for the mosaic's eccentricity
            [oiEnsemble, psfEnsemble] = ...
                app.components.coneMosaic.oiEnsembleGenerate(app.mosaicEccentricityDegs, ...
                'zernikeDataBase', 'Polans2015', ...
                'subjectID', app.PolansSubjectID, ...
                'pupilDiameterMM', app.pupilDiameterMM);
           
            % Update all components with new optics
            app.components.psf = psfEnsemble{1};
            app.components.optics = oiEnsemble{1};
            
            % Update progress bar
            dialogBox.Value = currentProgressValue+2*deltaProgressValue; 
            dialogBox.Message = 'Rendering cone mosaic';
            pause(.01);
            
            % Visualize the generated cone mosaic
            app.components.coneMosaic.visualize(...
                'figureHandle', app.mainView, ...
                'axesHandle', app.mosaicView, ...
                'crossHairsOnFovea', true, ...
                'fontSize', 14, ...
                'plotTitle',  ' ');
            
            % Visualized the psf
            app.updatePSFView();
            
            % Close progress bar
            if (currentProgressValue == 0)
                close(dialogBox);
            end
            
        end
        
        function readRadialEccentricityDegsSlider(app, value)
            actualValues = round(logspace(log10(0.15),log10(40), 9)*100)/100;
            actualValues = [0 actualValues(1:end-1)];
            app.radialEccentricityDegs = interp1(0:(numel(actualValues)-1), actualValues, value);
            app.mosaicEccentricityDegs(1) = app.radialEccentricityDegs * cosd(app.polarEccentricityDegs);
            app.mosaicEccentricityDegs(2) = app.radialEccentricityDegs * sind(app.polarEccentricityDegs);
        end
        
        function readPolarEccentricityDegsSlider(app, value)
            app.polarEccentricityDegs =-value;
            app.mosaicEccentricityDegs(1) = app.radialEccentricityDegs * cosd(app.polarEccentricityDegs);
            app.mosaicEccentricityDegs(2) = app.radialEccentricityDegs * sind(app.polarEccentricityDegs);
        end
        
        function readSizeDegsSlider(app, value)
            actualValues = [0.07 0.15 0.3 0.6 1.2 2.5 5 10];
            app.mosaicSizeDegs  = interp1(1:numel(actualValues), actualValues, value);
        end
        
        function updateRadialEccentricitySliderText(app)
            radialEccentricity = sqrt(sum(app.mosaicEccentricityDegs.^2,2));
            if (app.mosaicEccentricityDegs < 1.0)
                 app.radialeccentricitydegsSliderLabel.Text = sprintf('radial eccentricity (degs): %2.2f', radialEccentricity );
            elseif (app.mosaicEccentricityDegs < 10.0)
                 app.radialeccentricitydegsSliderLabel.Text = sprintf('radial eccentricity (degs): %2.1f', radialEccentricity);
            else
                 app.radialeccentricitydegsSliderLabel.Text = sprintf('radial eccentricity (degs): %2.0f', radialEccentricity);
            end
        end
        
        function updateMosaicSizeSliderText(app)
            if (app.mosaicSizeDegs < 1.0)
                 app.fieldofviewdegsSliderLabel.Text =  sprintf('field of view (degs): %2.2f', app.mosaicSizeDegs);
            elseif (app.mosaicSizeDegs < 10.0)
                 app.fieldofviewdegsSliderLabel.Text = sprintf('field of view (degs): %2.1f', app.mosaicSizeDegs);
            else
                 app.fieldofviewdegsSliderLabel.Text = sprintf('field of view(degs): %2.0f', app.mosaicSizeDegs);
            end
        end
        
        function initializePolarEccentricitySliderTicks(app)
            app.polareccentricitydegsSlider.MajorTicks = 0:45:360;
            app.polareccentricitydegsSlider.MajorTickLabels = {'360', '315', '270', '225', '180',  '135',  '90',   '45', '0'};
            app.polareccentricitydegsSlider.MinorTicks = [];
        end
        
        function updateVisualFieldView(app)
            app.mosaicEccentricityDegs = app.radialEccentricityDegs * [cosd(app.polarEccentricityDegs) sind(app.polarEccentricityDegs)];
            if (log10(app.radialEccentricityDegs)>-1)
                logRadius = app.maxEcc*(1+log10(app.radialEccentricityDegs))/(1+log10(app.maxEcc));
            else
                logRadius = 0;
            end
            
            set(app.mosaicOnVisualFieldPlotHandle, ...
                'XData',logRadius*cosd(app.polarEccentricityDegs), ...
                'YData',logRadius*sind(app.polarEccentricityDegs));
            app.updateRadialEccentricitySliderText();
            drawnow
        end

        % Method to update the displayed PSF
        function updatePSFView(app)
            
            % Find index of visualized wavelength
            [~, wIdx] = min(abs(app.components.psf.supportWavelength-app.visualizedWavelength));
            
            % Extract PSF at the desired wavelength
            wavePSF = squeeze(app.components.psf.data(:,:,wIdx));
            
            % Normalize to unit amplitude
            wavePSF = wavePSF/max(wavePSF(:));
            zLevels = 0.1:0.1:0.9; xyRangeArcMin = 5;
            
            % Render it
            cla(app.psfView);
            PolansOptics.renderPSF(app.psfView, app.components.psf.supportX, app.components.psf.supportY, wavePSF, ...
                    xyRangeArcMin, zLevels,  gray(1024), [0 0 0], 'plotTitle',  ' ');
    
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.initializeSimulationParams();
            app.initializeViews();
            app.initializeGUIcomponents();
            app.initializeSimulationComponents();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.mainView.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {867, 867};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {556, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: radialeccentricitydegsSlider
        function radialeccentricitydegsSliderUpdated(app, event)
            app.readRadialEccentricityDegsSlider(app.radialeccentricitydegsSlider.Value);
            app.updateRadialEccentricitySliderText();
            regenerateMosaic(app, [], 0,1);
        end

        % Value changed function: fieldofviewdegsSlider
        function sideDegsSliderUpdated(app, event)
            app.readSizeDegsSlider(app.fieldofviewdegsSlider.Value);
            app.updateMosaicSizeSliderText();
            regenerateMosaic(app, [], 0, 1);
        end

        % Value changing function: radialeccentricitydegsSlider
        function radialeccentricitySliderContinuousUpdate(app, event)
            app.readRadialEccentricityDegsSlider(event.Value);
            app.updateVisualFieldView();
        end

        % Value changed function: polareccentricitydegsSlider
        function polareccentricitydegsSliderUpdated(app, event)
            app.readPolarEccentricityDegsSlider(app.polareccentricitydegsSlider.Value);
            regenerateMosaic(app, [], 0,1);
        end

        % Value changing function: polareccentricitydegsSlider
        function polareccentricitySliderContinuousUpdate(app, event)
            app.readPolarEccentricityDegsSlider(event.Value);
            app.updateVisualFieldView();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainView = uifigure('Visible', 'off');
            app.mainView.Name = 'Interactive ISETBio CSF Generator, Written by N.P. Cottaris, ISETBio Team, 2021';
            app.mainView.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.mainView);
            app.LeftPanel = uipanel(app.GridLayout);
            app.visualfieldparamsPanel = uipanel(app.LeftPanel);
            app.visualfieldparamsPanel.Title = 'visual field params';
            app.radialeccentricitydegsSliderLabel = uilabel(app.visualfieldparamsPanel);
            app.radialeccentricitydegsSliderLabel.Tag = 'eccntricitySliderText';
            app.radialeccentricitydegsSliderLabel.Text = 'radial eccentricity (degs)';
            app.radialeccentricitydegsSlider = uislider(app.visualfieldparamsPanel);
            app.radialeccentricitydegsSlider.Limits = [0 8];
            app.radialeccentricitydegsSlider.MajorTicks = [0 1 2 3 4 5 6 7 8];
            app.radialeccentricitydegsSlider.MajorTickLabels = {'0', '.15', '.3', '.6', '1.2', '2.5', '5', '10', '20'};
            app.radialeccentricitydegsSlider.ValueChangedFcn = createCallbackFcn(app, @radialeccentricitydegsSliderUpdated, true);
            app.radialeccentricitydegsSlider.ValueChangingFcn = createCallbackFcn(app, @radialeccentricitySliderContinuousUpdate, true);
            app.radialeccentricitydegsSlider.MinorTicks = [];
            app.radialeccentricitydegsSlider.Value = 1;
            app.polareccentricitydegsSlider = uiknob(app.visualfieldparamsPanel, 'continuous');
            app.polareccentricitydegsSlider.Limits = [0 360];
            app.polareccentricitydegsSlider.MajorTicks = [0 90 180 270 360];
            app.polareccentricitydegsSlider.MajorTickLabels = {'0', '90 ', '180 ', '270 ', '360', ''};
            app.polareccentricitydegsSlider.ValueChangedFcn = createCallbackFcn(app, @polareccentricitydegsSliderUpdated, true);
            app.polareccentricitydegsSlider.ValueChangingFcn = createCallbackFcn(app, @polareccentricitySliderContinuousUpdate, true);
            app.polareccentricitydegsSlider.MinorTicks = [];
            app.polareccentricitydegsSlider.Value = 360;
            app.fieldofviewdegsSliderLabel = uilabel(app.visualfieldparamsPanel);
            app.fieldofviewdegsSliderLabel.Text = 'field of view (degs)';
            app.fieldofviewdegsSlider = uislider(app.visualfieldparamsPanel);
            app.fieldofviewdegsSlider.Limits = [1 8];
            app.fieldofviewdegsSlider.MajorTicks = [1 2 3 4 5 6 7 8];
            app.fieldofviewdegsSlider.MajorTickLabels = {'0.07 ', '0.15 ', '0.3 ', '0.6 ', '1.2', ' 2.5', ' 5', '10'};
            app.fieldofviewdegsSlider.ValueChangedFcn = createCallbackFcn(app, @sideDegsSliderUpdated, true);
            app.fieldofviewdegsSlider.MinorTicks = [];
            app.fieldofviewdegsSlider.Value = 4;
            app.Switch_2 = uiswitch(app.visualfieldparamsPanel, 'slider');
            app.Switch_2.Items = {'left eye', 'right eye'};
            app.Switch_2.Value = 'left eye';
            app.visualFieldView = uiaxes(app.visualfieldparamsPanel);
            xlabel(app.visualFieldView, 'X')
            ylabel(app.visualFieldView, 'Y')
            zlabel(app.visualFieldView, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.stimulationthresholdparamsPanel = uipanel(app.RightPanel);
            app.stimulationthresholdparamsPanel.Title = 'stimulation / threshold params';
            app.sf_noLabel = uilabel(app.stimulationthresholdparamsPanel);
            app.sf_noLabel.Text = 'sf_no ';
            app.sf_noSpinner = uispinner(app.stimulationthresholdparamsPanel);
            app.sf_noSpinner.Limits = [1 16];
            app.sf_noSpinner.Value = 4;
            app.sf_maxLabel = uilabel(app.stimulationthresholdparamsPanel);
            app.sf_maxLabel.Text = 'sf_max ';
            app.sf_maxSpinner = uispinner(app.stimulationthresholdparamsPanel);
            app.sf_maxSpinner.Step = 5;
            app.sf_maxSpinner.Limits = [10 60];
            app.sf_maxSpinner.Value = 30;
            app.sf_minLabel = uilabel(app.stimulationthresholdparamsPanel);
            app.sf_minLabel.Text = 'sf_min ';
            app.sf_minSpinner = uispinner(app.stimulationthresholdparamsPanel);
            app.sf_minSpinner.Step = 0.5;
            app.sf_minSpinner.Limits = [1 10];
            app.sf_minSpinner.Value = 3;
            app.slope_minSpinnerLabel = uilabel(app.stimulationthresholdparamsPanel);
            app.slope_minSpinnerLabel.Text = 'slope_min ';
            app.slope_minSpinner = uispinner(app.stimulationthresholdparamsPanel);
            app.slope_minSpinner.Step = 10;
            app.slope_minSpinner.Limits = [300 800];
            app.slope_minSpinner.Value = 350;
            app.slope_maxSpinnerLabel = uilabel(app.stimulationthresholdparamsPanel);
            app.slope_maxSpinnerLabel.Text = 'slope_max ';
            app.slope_maxSpinner = uispinner(app.stimulationthresholdparamsPanel);
            app.slope_maxSpinner.Step = 10;
            app.slope_maxSpinner.Limits = [300 800];
            app.slope_maxSpinner.Value = 350;
            app.traintrialsSpinnerLabel = uilabel(app.stimulationthresholdparamsPanel);
            app.traintrialsSpinnerLabel.Text = {'train trials'; ''};
            app.traintrialsSpinner = uispinner(app.stimulationthresholdparamsPanel);
            app.traintrialsSpinner.Limits = [1 100000];
            app.traintrialsSpinner.Value = 100;
            app.testtrialsSpinnerLabel = uilabel(app.stimulationthresholdparamsPanel);
            app.testtrialsSpinnerLabel.Text = {'test trials'; ''};
            app.testtrialsSpinner = uispinner(app.stimulationthresholdparamsPanel);
            app.testtrialsSpinner.Limits = [1 100000];
            app.testtrialsSpinner.Value = 100;
            app.csfView = uiaxes(app.stimulationthresholdparamsPanel);
            title(app.csfView, {''; ''})
            xlabel(app.csfView, 'X')
            ylabel(app.csfView, 'Y')
            zlabel(app.csfView, 'Z')
            app.psychometricFunctionView = uiaxes(app.stimulationthresholdparamsPanel);
            title(app.psychometricFunctionView, {''; ''})
            xlabel(app.psychometricFunctionView, 'X')
            ylabel(app.psychometricFunctionView, 'Y')
            zlabel(app.psychometricFunctionView, 'Z')
            app.conemosaicparamsPanel = uipanel(app.RightPanel);
            app.conemosaicparamsPanel.Title = 'cone mosaic params';
            app.Label = uilabel(app.conemosaicparamsPanel);
            app.Label.Text = '';
            app.eccvaryingmacularpigmentCheckBox = uicheckbox(app.conemosaicparamsPanel);
            app.eccvaryingmacularpigmentCheckBox.Text = 'ecc-varying macular pigment';
            app.eccvaryingaperturelightgatheringCheckBox = uicheckbox(app.conemosaicparamsPanel);
            app.eccvaryingaperturelightgatheringCheckBox.Text = 'ecc-varying aperture (light gathering)';
            app.eccvaryingapertureblurCheckBox = uicheckbox(app.conemosaicparamsPanel);
            app.eccvaryingapertureblurCheckBox.Text = 'ecc-varying aperture blur';
            app.fixationaleyemovementsCheckBox = uicheckbox(app.conemosaicparamsPanel);
            app.fixationaleyemovementsCheckBox.Text = 'fixational eye movements';
            app.Switch = uiswitch(app.conemosaicparamsPanel, 'slider');
            app.Switch.Items = {'degrees', 'microns'};
            app.Switch.Value = 'degrees';
            app.integrationtimemsecSpinnerLabel = uilabel(app.conemosaicparamsPanel);
            app.integrationtimemsecSpinnerLabel.Text = {'integration time (msec)'; ''};
            app.integrationtimemsecSpinner = uispinner(app.conemosaicparamsPanel);
            app.integrationtimemsecSpinner.Limits = [1 1000];
            app.integrationtimemsecSpinner.Value = 5;
            app.mosaicView = uiaxes(app.conemosaicparamsPanel);
            xlabel(app.mosaicView, 'X')
            ylabel(app.mosaicView, 'Y')
            zlabel(app.mosaicView, 'Z')
            app.opticalparamsPanel = uipanel(app.RightPanel);
            app.opticalparamsPanel.Title = 'optical params';
            app.conesSwitch = uiswitch(app.opticalparamsPanel, 'slider');
            app.conesSwitch.Items = {'show cones', 'no cones'};
            app.conesSwitch.Value = 'show cones';
            app.PolanssubjectLabel = uilabel(app.opticalparamsPanel);
            app.PolanssubjectLabel.Text = 'Polans subject';
            app.PolanssubjectSpinner = uispinner(app.opticalparamsPanel);
            app.PolanssubjectSpinner.Step = 0.5;
            app.PolanssubjectSpinner.Limits = [1 10];
            app.PolanssubjectSpinner.Value = 8;
            app.eccvaryingopticalblurxygridCheckBox = uicheckbox(app.opticalparamsPanel);
            app.eccvaryingopticalblurxygridCheckBox.Text = 'ecc-varying optical blur (x/y grid)';
            app.xsamplesSpinnerLabel = uilabel(app.opticalparamsPanel);
            app.xsamplesSpinnerLabel.Text = 'x-samples';
            app.xsamplesSpinner = uispinner(app.opticalparamsPanel);
            app.xsamplesSpinner.Limits = [1 16];
            app.xsamplesSpinner.Value = 4;
            app.ysamplesSpinnerLabel = uilabel(app.opticalparamsPanel);
            app.ysamplesSpinnerLabel.Text = 'y-samples';
            app.ysamplesSpinner = uispinner(app.opticalparamsPanel);
            app.ysamplesSpinner.Limits = [1 16];
            app.ysamplesSpinner.Value = 4;
            app.pupildiametermmLabel = uilabel(app.opticalparamsPanel);
            app.pupildiametermmLabel.Text = 'pupil diameter (mm) ';
            app.pupildiametermmSpinner = uispinner(app.opticalparamsPanel);
            app.pupildiametermmSpinner.Step = 0.1;
            app.pupildiametermmSpinner.Limits = [1 6];
            app.pupildiametermmSpinner.Value = 3;
            app.psfView = uiaxes(app.opticalparamsPanel);
            xlabel(app.psfView, 'X')
            ylabel(app.psfView, 'Y')
            zlabel(app.psfView, 'Z')
            app.stimulusparamsPanel = uipanel(app.RightPanel);
            app.stimulusparamsPanel.Title = 'stimulus params';
            app.cLSpinnerLabel = uilabel(app.stimulusparamsPanel);
            app.cLSpinnerLabel.Text = 'cL ';
            app.cMSpinner = uispinner(app.stimulusparamsPanel);
            app.cMSpinner.Step = 0.5;
            app.cMSpinner.Limits = [-100 100];
            app.cMSpinner.Value = 10;
            app.cSSpinner = uispinner(app.stimulusparamsPanel);
            app.cSSpinner.Step = 0.5;
            app.cSSpinner.Limits = [-100 100];
            app.cLSpinner = uispinner(app.stimulusparamsPanel);
            app.cLSpinner.Step = 0.5;
            app.cLSpinner.Limits = [-100 100];
            app.cLSpinner.Value = 10;
            app.cMSpinnerLabel = uilabel(app.stimulusparamsPanel);
            app.cMSpinnerLabel.Text = 'cM ';
            app.cSLabel = uilabel(app.stimulusparamsPanel);
            app.cSLabel.Text = 'cS ';
            app.w_minSpinner = uispinner(app.stimulusparamsPanel);
            app.w_minSpinner.Step = 10;
            app.w_minSpinner.Limits = [300 800];
            app.w_minSpinner.Value = 350;
            app.w_maxSpinner = uispinner(app.stimulusparamsPanel);
            app.w_maxSpinner.Step = 10;
            app.w_maxSpinner.Limits = [400 800];
            app.w_maxSpinner.Value = 750;
            app.w_noSpinner = uispinner(app.stimulusparamsPanel);
            app.w_noSpinner.Limits = [5 50];
            app.w_noSpinner.Value = 31;
            app.w_minSpinnerLabel = uilabel(app.stimulusparamsPanel);
            app.w_minSpinnerLabel.Text = 'w_min ';
            app.w_maxSpinnerLabel = uilabel(app.stimulusparamsPanel);
            app.w_maxSpinnerLabel.Text = 'w_max ';
            app.w_noSpinnerLabel = uilabel(app.stimulusparamsPanel);
            app.w_noSpinnerLabel.Text = 'w_no ';
            app.stimulusdurationmsSpinnerLabel = uilabel(app.stimulusparamsPanel);
            app.stimulusdurationmsSpinnerLabel.Text = {'stimulus duration (ms)'; ''};
            app.stimulusdurationmsSpinner = uispinner(app.stimulusparamsPanel);
            app.stimulusdurationmsSpinner.Step = 10;
            app.stimulusdurationmsSpinner.Limits = [10 1000];
            app.stimulusdurationmsSpinner.Value = 100;
            app.UIAxes = uiaxes(app.stimulusparamsPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.mainView.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ISETBioCSFGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainView)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainView)
        end
    end
end