classdef ISETBioCSFGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainView                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        opticsTab                       matlab.ui.container.Tab
        Panel_3                         matlab.ui.container.Panel
        subjectIDSpinnerLabel           matlab.ui.control.Label
        opticsSubjectIDSpinner          matlab.ui.control.Spinner
        subjectdatasetLabel             matlab.ui.control.Label
        opticsSubjectDataBaseDropDown   matlab.ui.control.DropDown
        pupilsizemmLabel                matlab.ui.control.Label
        opticsPupilSizeSpinner          matlab.ui.control.Spinner
        opticsSubtractCentralRefractionCheckBox  matlab.ui.control.CheckBox
        xpositionsLabel                 matlab.ui.control.Label
        opticsPSFGridXPositionsSpinner  matlab.ui.control.Spinner
        ypositionsLabel                 matlab.ui.control.Label
        opticsPSFGridYPositionsSpinner  matlab.ui.control.Spinner
        PSFspatialgridLabel             matlab.ui.control.Label
        opticsVisualizedWavelengthSlider  matlab.ui.control.Slider
        wavelengthnmSliderLabel         matlab.ui.control.Label
        opticsView                      matlab.ui.control.UIAxes
        opticsViewPSFGrid               matlab.ui.control.UIAxes
        coneMosaicTab                   matlab.ui.container.Tab
        Panel_4                         matlab.ui.container.Panel
        coneMosaicViewModeKnob          matlab.ui.control.DiscreteKnob
        coneMosaicView                  matlab.ui.control.UIAxes
        fixationalEyeMovementsTab       matlab.ui.container.Tab
        Panel_6                         matlab.ui.container.Panel
        mRGCmosaicTab                   matlab.ui.container.Tab
        pRGCmosaicTab                   matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        visualFieldTab                  matlab.ui.container.Tab
        Panel_5                         matlab.ui.container.Panel
        visualFieldEyeSwitch            matlab.ui.control.Switch
        polareccentricitydegsLabel      matlab.ui.control.Label
        visualFieldPolarEccentricityKnob  matlab.ui.control.Knob
        radialeccentricitydegsLabel     matlab.ui.control.Label
        visualFieldRadialEccentricitySlider  matlab.ui.control.Slider
        magnificationfactormicronsdegLabel  matlab.ui.control.Label
        visualFieldMagnificationFactorEditField  matlab.ui.control.NumericEditField
        fieldofviewdegsLabel            matlab.ui.control.Label
        visualFieldFieldOfViewSlider    matlab.ui.control.Slider
        visualFieldView                 matlab.ui.control.UIAxes
        stimulusTab                     matlab.ui.container.Tab
        Panel_7                         matlab.ui.container.Panel
        stimulusdurationmsecSpinnerLabel  matlab.ui.control.Label
        stimulusDurationSpinner         matlab.ui.control.Spinner
        meanluminancecdm2SpinnerLabel   matlab.ui.control.Label
        stimulusMeanLuminanceSpinner    matlab.ui.control.Spinner
        temporalfrequencyHzSpinnerLabel  matlab.ui.control.Label
        stimulusTemporalFrequencySpinner  matlab.ui.control.Spinner
        orientationdegLabel             matlab.ui.control.Label
        stimulusOrientationSpinner      matlab.ui.control.Spinner
        presentationmodeLabel           matlab.ui.control.Label
        stimulusPresentationModeDropDown  matlab.ui.control.DropDown
        spatialenvelopeLabel            matlab.ui.control.Label
        stimulusSpatialEnvelopeDropDown  matlab.ui.control.DropDown
        spatialfrequencycdegSpinnerLabel  matlab.ui.control.Label
        stimulusSpatialFrequencySpinner  matlab.ui.control.Spinner
        sizedegSpinnerLabel             matlab.ui.control.Label
        stimulusSizeSpinner             matlab.ui.control.Spinner
        resolutionpixelsSpinnerLabel    matlab.ui.control.Label
        stimulusResolutionSpinner       matlab.ui.control.Spinner
        xSpinnerLabel                   matlab.ui.control.Label
        stimulusSpatialPositionXSpinner  matlab.ui.control.Spinner
        positiondegLabel                matlab.ui.control.Label
        ySpinnerLabel                   matlab.ui.control.Label
        stimulusSpatialPositionYSpinner  matlab.ui.control.Spinner
        stimulusMosaicCenteredCheckBox  matlab.ui.control.CheckBox
        LconecontrastSpinnerLabel       matlab.ui.control.Label
        stimulusLconeContrastSpinner    matlab.ui.control.Spinner
        MconecontrastSpinnerLabel       matlab.ui.control.Label
        stimulusMconeContrastSpinner    matlab.ui.control.Spinner
        SconecontrastSpinnerLabel       matlab.ui.control.Label
        stimulusSconeContrastSpinner    matlab.ui.control.Spinner
        wavelengthsupportminnmSpinnerLabel  matlab.ui.control.Label
        stimulusWavelengthSupportMinSpinner  matlab.ui.control.Spinner
        wavelengthsupportmaxnmSpinnerLabel  matlab.ui.control.Label
        stimulusWavelengthSupportMaxSpinner  matlab.ui.control.Spinner
        wavelengthsupportstepsizenmSpinnerLabel  matlab.ui.control.Label
        stimulusWavelengthSupportStepSizeSpinner  matlab.ui.control.Spinner
        stimulusView                    matlab.ui.control.UIAxes
        performanceassessmentTab        matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        leftStatusField                 matlab.ui.control.EditField
        Panel_2                         matlab.ui.container.Panel
        rightStatusField                matlab.ui.control.EditField
    end

    
    properties (Access = public)
        % Params for various processing pipeline components
        visualFieldParams               % struct with all visual field params
        coneMosaicParams                % struct with all cone mosaic params
        opticsParams                    % struct with all optics params
        stimParams                      % struct with all stimulus params
        
        % Components of the processing pipeline
        components                      % struct containing the various processing objects
        
        % Handles to various plots
        odOnVisualFieldPlotHandle       % handle to the optic disk plot
        mosaicOnVisualFieldPlotHandle   % handle to the mosaic outline plot
        psfDensityPlotHandle            % handle to the density plot of the PSF
        coneOutlineOnPSFPlotHandles     % array of handles to the cones of PSF plot
        centralConeOutlinesArcMin       % x,y coords of central cones in current mosaic
        centralConeOutlinesNum = 7+13;
        
        % XYlimits for various zoomable plots
        opticsViewXLimsArcMin = [];
        opticsViewYLimsArcMin = [];
        coneMosaicViewXLimsDegs = [];
        coneMosaicViewXLimsMicrons = [];
        coneMosaicViewYLimsDegs = [];
        coneMosaicViewYLimsMicrons = [];
    end
    
    methods (Access = public)
        
        % Handler function for optics zoom event
        function handleOpticsZoomEvent(app,src,evnt)
            switch src.Name
                case 'XLim'
                    app.opticsViewXLimsArcMin = evnt.AffectedObject.XLim;
        
                case 'YLim'
                    app.opticsViewYLimsArcMin = evnt.AffectedObject.YLim;
            end
            if (max(app.opticsViewXLimsArcMin) < 2)
                set(app.opticsView, 'XTick', -2:0.5:2);
            elseif (max(app.opticsViewXLimsArcMin) < 5)
                set(app.opticsView, 'XTick', -5:1:5);
            elseif (max(app.opticsViewXLimsArcMin) < 10)
                set(app.opticsView, 'XTick', -10:2:10);
            elseif (max(app.opticsViewXLimsArcMin) < 20)
                set(app.opticsView, 'XTick', -20:4:20);
            else
                set(app.opticsView, 'XTick', -30:5:30);
            end
        end
        
        % Handler function for cone mosaic zoom event
        function handleConeMosaicViewZoomEvent(app,src,evnt)
            switch src.Name
                case 'XLim'
                    switch (app.coneMosaicParams.visualizationDomain)
                        case 'degrees'
                            app.coneMosaicViewXLimsDegs = evnt.AffectedObject.XLim;
                        case 'microns'
                            app.coneMosaicViewXLimsMicrons = evnt.AffectedObject.XLim;
                    end
                case 'YLim'
                    switch (app.coneMosaicParams.visualizationDomain)
                        case 'degrees'
                            app.coneMosaicViewYLimsDegs = evnt.AffectedObject.YLim;
                        case 'microns'
                            app.coneMosaicViewYLimsMicrons = evnt.AffectedObject.YLim;
                    end
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            CSFGeneratorApp.initialize.simulationParams(app);
            CSFGeneratorApp.initialize.guiComponents(app);
            CSFGeneratorApp.initialize.allViews(app);
            
            CSFGeneratorApp.generate.processingPipeline(app);
            CSFGeneratorApp.render.visualFieldView(app, 'update');
        end

        % Value changed function: visualFieldEyeSwitch
        function visualFieldEyeSwitchValueChanged(app, event)
            value = app.visualFieldEyeSwitch.Value;
            CSFGeneratorApp.decode.eyeSwitch(app, 'sliderToValue', value);
            CSFGeneratorApp.generate.processingPipeline(app);
            CSFGeneratorApp.render.visualFieldView(app, 'update');
        end

        % Value changed function: visualFieldPolarEccentricityKnob
        function visualFieldPolarEccentricityKnobValueChanged(app, event)
            value = app.visualFieldPolarEccentricityKnob.Value;
            CSFGeneratorApp.decode.polarEccentricitySlider(app, 'sliderToValue', value);
            CSFGeneratorApp.render.visualFieldView(app, 'update');
            CSFGeneratorApp.generate.processingPipeline(app);
        end

        % Value changing function: visualFieldPolarEccentricityKnob
        function visualFieldPolarEccentricityKnobValueChanging(app, event)
            changingValue = event.Value;
            CSFGeneratorApp.decode.polarEccentricitySlider(app, 'sliderToValue', changingValue);
            CSFGeneratorApp.render.visualFieldView(app, 'update');
        end

        % Value changed function: 
        % visualFieldRadialEccentricitySlider
        function visualFieldRadialEccentricitySliderValueChanged(app, event)
            value = app.visualFieldRadialEccentricitySlider.Value;
            CSFGeneratorApp.decode.radialEccentricitySlider(app, 'sliderToValue', value);
            CSFGeneratorApp.render.visualFieldView(app, 'update');
            CSFGeneratorApp.generate.processingPipeline(app);
        end

        % Value changing function: 
        % visualFieldRadialEccentricitySlider
        function visualFieldRadialEccentricitySliderValueChanging(app, event)
            changingValue = event.Value;
            CSFGeneratorApp.decode.radialEccentricitySlider(app, 'sliderToValue', changingValue);
            CSFGeneratorApp.render.visualFieldView(app, 'update');
        end

        % Value changed function: visualFieldFieldOfViewSlider
        function visualFieldFieldOfViewSliderValueChanged(app, event)
            value = app.visualFieldFieldOfViewSlider.Value;
            CSFGeneratorApp.decode.fieldOfViewSlider(app, 'sliderToValue', value);
            CSFGeneratorApp.render.visualFieldView(app, 'update');
            CSFGeneratorApp.generate.processingPipeline(app);
        end

        % Value changing function: visualFieldFieldOfViewSlider
        function visualFieldFieldOfViewSliderValueChanging(app, event)
            changingValue = event.Value;
            CSFGeneratorApp.decode.fieldOfViewSlider(app, 'sliderToValue', changingValue);
            CSFGeneratorApp.render.visualFieldView(app, 'update');
        end

        % Value changed function: opticsVisualizedWavelengthSlider
        function opticsVisualizedWavelengthSliderChanged(app, event)
            value = app.opticsVisualizedWavelengthSlider.Value;
            CSFGeneratorApp.decode.opticsWavelengthSlider(app, 'sliderToValue', value);
            CSFGeneratorApp.render.opticsView(app, 'update');
        end

        % Value changing function: opticsVisualizedWavelengthSlider
        function opticsWavelengthSliderChanging(app, event)
            changingValue = event.Value;
            CSFGeneratorApp.decode.opticsWavelengthSlider(app, 'sliderToValue', changingValue);
            CSFGeneratorApp.render.opticsView(app, 'update');
        end

        % Value changed function: opticsPSFGridXPositionsSpinner
        function opticsPSFGridXPositionsChanged(app, event)
            value = app.opticsPSFGridXPositionsSpinner.Value;
            
        end

        % Value changed function: opticsPSFGridYPositionsSpinner
        function opticsPSFGridYPositionsChanged(app, event)
            value = app.opticsPSFGridYPositionsSpinner.Value;
            
        end

        % Value changed function: opticsSubjectDataBaseDropDown
        function opticsSubjectDataBaseChanged(app, event)
            value = app.opticsSubjectDataBaseDropDown.Value;
            
        end

        % Value changed function: opticsSubjectIDSpinner
        function opticsSubjectIDChanged(app, event)
            value = app.opticsSubjectIDSpinner.Value;
            CSFGeneratorApp.decode.opticsSubjectIDSpinner(app, 'sliderToValue', value);
        end

        % Value changed function: 
        % opticsSubtractCentralRefractionCheckBox
        function opticsSubtractCentralRefractionChanged(app, event)
            value = app.opticsSubtractCentralRefractionCheckBox.Value;
            CSFGeneratorApp.decode.opticsSubtractCentralRefractionCheckBox(app, 'sliderToValue', value);
        end

        % Value changed function: opticsPupilSizeSpinner
        function opticsPupilSizeChanged(app, event)
            value = app.opticsPupilSizeSpinner.Value;
            CSFGeneratorApp.decode.opticsPupilSizeSpinner(app, 'sliderToValue', value);
        end

        % Value changed function: stimulusPresentationModeDropDown
        function stimulusPresentationModeChanged(app, event)
            value = app.stimulusPresentationModeDropDown.Value;
            
        end

        % Value changed function: stimulusDurationSpinner
        function stimulusDurationChanged(app, event)
            value = app.stimulusDurationSpinner.Value;
            
        end

        % Value changed function: stimulusTemporalFrequencySpinner
        function stimulusTemporalFrequencyChanged(app, event)
            value = app.stimulusTemporalFrequencySpinner.Value;
            
        end

        % Value changed function: stimulusSpatialEnvelopeDropDown
        function stimulusSpatialEnvelopeChanged(app, event)
            value = app.stimulusSpatialEnvelopeDropDown.Value;
            
        end

        % Value changed function: stimulusOrientationSpinner
        function stimulusOrientationChanged(app, event)
            value = app.stimulusOrientationSpinner.Value;
            
        end

        % Value changed function: stimulusSpatialFrequencySpinner
        function stimulusSpatialFrequencyChanged(app, event)
            value = app.stimulusSpatialFrequencySpinner.Value;
            
        end

        % Value changed function: stimulusSizeSpinner
        function stimulusSizeChanged(app, event)
            value = app.stimulusSizeSpinner.Value;
            
        end

        % Value changed function: stimulusResolutionSpinner
        function stimulusResolutionChanged(app, event)
            value = app.stimulusResolutionSpinner.Value;
            
        end

        % Value changed function: stimulusSpatialPositionXSpinner
        function stimulusSpatialPositionXChanged(app, event)
            value = app.stimulusSpatialPositionXSpinner.Value;
            
        end

        % Value changed function: stimulusSpatialPositionYSpinner
        function stimulusSpatialPositionYChanged(app, event)
            value = app.stimulusSpatialPositionYSpinner.Value;
            
        end

        % Value changed function: stimulusMeanLuminanceSpinner
        function stimulusMeanLuminanceChanged(app, event)
            value = app.stimulusMeanLuminanceSpinner.Value;
            
        end

        % Value changed function: stimulusLconeContrastSpinner
        function stimulusLconeContrastChanged(app, event)
            value = app.stimulusLconeContrastSpinner.Value;
            
        end

        % Value changed function: stimulusMconeContrastSpinner
        function stimulusMconeContrastChanged(app, event)
            value = app.stimulusMconeContrastSpinner.Value;
            
        end

        % Value changed function: stimulusSconeContrastSpinner
        function stimulusSconeContrastChanged(app, event)
            value = app.stimulusSconeContrastSpinner.Value;
            
        end

        % Value changed function: 
        % stimulusWavelengthSupportMinSpinner
        function stimulusWavelengthSupportMinChanged(app, event)
            value = app.stimulusWavelengthSupportMinSpinner.Value;
            
        end

        % Value changed function: 
        % stimulusWavelengthSupportMaxSpinner
        function stimulusWavelengthSupportMaxChanged(app, event)
            value = app.stimulusWavelengthSupportMaxSpinner.Value;
            
        end

        % Value changed function: 
        % stimulusWavelengthSupportStepSizeSpinner
        function stimulusWavelengthSupportStepSizeChanged(app, event)
            value = app.stimulusWavelengthSupportStepSizeSpinner.Value;
            
        end

        % Value changed function: stimulusMosaicCenteredCheckBox
        function stimulusMosaicCenteredChanged(app, event)
            value = app.stimulusMosaicCenteredCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainView = uifigure('Visible', 'off');
            app.mainView.Name = 'ISETBio CSF Generator, Developed by N.P. Cottaris, ISETBio Team, 2021';
            app.GridLayout = uigridlayout(app.mainView);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.opticsTab = uitab(app.TabGroup);
            app.opticsTab.Title = 'optics';
            app.Panel_3 = uipanel(app.opticsTab);
            app.subjectIDSpinnerLabel = uilabel(app.Panel_3);
            app.subjectIDSpinnerLabel.Text = 'subject ID ';
            app.opticsSubjectIDSpinner = uispinner(app.Panel_3);
            app.opticsSubjectIDSpinner.Limits = [1 10];
            app.opticsSubjectIDSpinner.ValueChangedFcn = createCallbackFcn(app, @opticsSubjectIDChanged, true);
            app.opticsSubjectIDSpinner.Value = 10;
            app.subjectdatasetLabel = uilabel(app.Panel_3);
            app.subjectdatasetLabel.Text = 'subject dataset ';
            app.opticsSubjectDataBaseDropDown = uidropdown(app.Panel_3);
            app.opticsSubjectDataBaseDropDown.Items = {'Artal', 'Polans2015'};
            app.opticsSubjectDataBaseDropDown.ValueChangedFcn = createCallbackFcn(app, @opticsSubjectDataBaseChanged, true);
            app.opticsSubjectDataBaseDropDown.Tooltip = {'The Polans dataset contains wavefront aberration measurements along both the horizontal and vertical axes. The Artal dataset contains measurements along the horizontal axis only.'};
            app.opticsSubjectDataBaseDropDown.Value = 'Polans2015';
            app.pupilsizemmLabel = uilabel(app.Panel_3);
            app.pupilsizemmLabel.Text = 'pupil size (mm) ';
            app.opticsPupilSizeSpinner = uispinner(app.Panel_3);
            app.opticsPupilSizeSpinner.Step = 0.5;
            app.opticsPupilSizeSpinner.Limits = [1 4];
            app.opticsPupilSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @opticsPupilSizeChanged, true);
            app.opticsPupilSizeSpinner.Value = 3;
            app.opticsSubtractCentralRefractionCheckBox = uicheckbox(app.Panel_3);
            app.opticsSubtractCentralRefractionCheckBox.ValueChangedFcn = createCallbackFcn(app, @opticsSubtractCentralRefractionChanged, true);
            app.opticsSubtractCentralRefractionCheckBox.Tooltip = {'Enabling this subtracts the defocus coefficient (Z4) measured at the fovea, from the defocus coefficients at all positions'};
            app.opticsSubtractCentralRefractionCheckBox.Text = ' subtract central refraction';
            app.xpositionsLabel = uilabel(app.Panel_3);
            app.xpositionsLabel.Enable = 'off';
            app.xpositionsLabel.Text = 'x-positions';
            app.opticsPSFGridXPositionsSpinner = uispinner(app.Panel_3);
            app.opticsPSFGridXPositionsSpinner.Limits = [1 10];
            app.opticsPSFGridXPositionsSpinner.ValueChangedFcn = createCallbackFcn(app, @opticsPSFGridXPositionsChanged, true);
            app.opticsPSFGridXPositionsSpinner.Enable = 'off';
            app.opticsPSFGridXPositionsSpinner.Tooltip = {'PSF samples along the horizontal axis'};
            app.opticsPSFGridXPositionsSpinner.Value = 3;
            app.ypositionsLabel = uilabel(app.Panel_3);
            app.ypositionsLabel.Enable = 'off';
            app.ypositionsLabel.Text = 'y-positions';
            app.opticsPSFGridYPositionsSpinner = uispinner(app.Panel_3);
            app.opticsPSFGridYPositionsSpinner.Limits = [1 10];
            app.opticsPSFGridYPositionsSpinner.ValueChangedFcn = createCallbackFcn(app, @opticsPSFGridYPositionsChanged, true);
            app.opticsPSFGridYPositionsSpinner.Enable = 'off';
            app.opticsPSFGridYPositionsSpinner.Tooltip = {'PSF samples along the vertical axis'};
            app.opticsPSFGridYPositionsSpinner.Value = 3;
            app.PSFspatialgridLabel = uilabel(app.Panel_3);
            app.PSFspatialgridLabel.Text = 'PSF spatial grid';
            app.opticsVisualizedWavelengthSlider = uislider(app.Panel_3);
            app.opticsVisualizedWavelengthSlider.Limits = [400 750];
            app.opticsVisualizedWavelengthSlider.ValueChangedFcn = createCallbackFcn(app, @opticsVisualizedWavelengthSliderChanged, true);
            app.opticsVisualizedWavelengthSlider.ValueChangingFcn = createCallbackFcn(app, @opticsWavelengthSliderChanging, true);
            app.opticsVisualizedWavelengthSlider.Value = 550;
            app.wavelengthnmSliderLabel = uilabel(app.Panel_3);
            app.wavelengthnmSliderLabel.Text = 'visualization wavelength (nm)';
            app.opticsView = uiaxes(app.opticsTab);
            xlabel(app.opticsView, 'X')
            zlabel(app.opticsView, 'Z')
            app.opticsView.YTick = [];
            app.opticsView.YTickLabel = '';
            app.opticsViewPSFGrid = uiaxes(app.opticsTab);
            zlabel(app.opticsViewPSFGrid, 'Z')
            app.opticsViewPSFGrid.XTick = [];
            app.opticsViewPSFGrid.XTickLabel = '';
            app.opticsViewPSFGrid.YTick = [];
            app.opticsViewPSFGrid.YTickLabel = '';
            app.coneMosaicTab = uitab(app.TabGroup);
            app.coneMosaicTab.Title = 'cone mosaic ';
            app.Panel_4 = uipanel(app.coneMosaicTab);
            app.coneMosaicViewModeKnob = uiknob(app.Panel_4, 'discrete');
            app.coneMosaicViewModeKnob.Items = {'cone types', 'cones+retinal image', 'activation', 'modulation', 'residual'};
            app.coneMosaicViewModeKnob.Tooltip = {'Selects which aspect of the cone mosaic and its activation by the stimulus is visualized'};
            app.coneMosaicViewModeKnob.Value = 'cone types';
            app.coneMosaicView = uiaxes(app.coneMosaicTab);
            xlabel(app.coneMosaicView, 'X')
            zlabel(app.coneMosaicView, 'Z')
            app.fixationalEyeMovementsTab = uitab(app.TabGroup);
            app.fixationalEyeMovementsTab.Title = 'fixational eye movements';
            app.Panel_6 = uipanel(app.fixationalEyeMovementsTab);
            app.mRGCmosaicTab = uitab(app.TabGroup);
            app.mRGCmosaicTab.Title = 'mRGC mosaic';
            app.pRGCmosaicTab = uitab(app.TabGroup);
            app.pRGCmosaicTab.Title = 'pRGC mosaic';
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.visualFieldTab = uitab(app.TabGroup2);
            app.visualFieldTab.Title = 'region of interest (visual field)';
            app.Panel_5 = uipanel(app.visualFieldTab);
            app.visualFieldEyeSwitch = uiswitch(app.Panel_5, 'slider');
            app.visualFieldEyeSwitch.Items = {'left eye', 'right eye'};
            app.visualFieldEyeSwitch.ValueChangedFcn = createCallbackFcn(app, @visualFieldEyeSwitchValueChanged, true);
            app.visualFieldEyeSwitch.Value = 'right eye';
            app.polareccentricitydegsLabel = uilabel(app.Panel_5);
            app.polareccentricitydegsLabel.Text = 'polar eccentricity (degs) ';
            app.visualFieldPolarEccentricityKnob = uiknob(app.Panel_5, 'continuous');
            app.visualFieldPolarEccentricityKnob.Limits = [0 360];
            app.visualFieldPolarEccentricityKnob.ValueChangedFcn = createCallbackFcn(app, @visualFieldPolarEccentricityKnobValueChanged, true);
            app.visualFieldPolarEccentricityKnob.ValueChangingFcn = createCallbackFcn(app, @visualFieldPolarEccentricityKnobValueChanging, true);
            app.radialeccentricitydegsLabel = uilabel(app.Panel_5);
            app.radialeccentricitydegsLabel.Text = {'radial eccentricity (degs) '; ''};
            app.visualFieldRadialEccentricitySlider = uislider(app.Panel_5);
            app.visualFieldRadialEccentricitySlider.ValueChangedFcn = createCallbackFcn(app, @visualFieldRadialEccentricitySliderValueChanged, true);
            app.visualFieldRadialEccentricitySlider.ValueChangingFcn = createCallbackFcn(app, @visualFieldRadialEccentricitySliderValueChanging, true);
            app.magnificationfactormicronsdegLabel = uilabel(app.Panel_5);
            app.magnificationfactormicronsdegLabel.Text = 'magnification factor (microns/deg)';
            app.visualFieldMagnificationFactorEditField = uieditfield(app.Panel_5, 'numeric');
            app.visualFieldMagnificationFactorEditField.Editable = 'off';
            app.visualFieldMagnificationFactorEditField.Tooltip = {'Retinal magnification factor (microns/deg) at the currect eccentricity. This changes a little bit with eccentricity,'};
            app.fieldofviewdegsLabel = uilabel(app.Panel_5);
            app.fieldofviewdegsLabel.Text = 'field of view (degs) ';
            app.visualFieldFieldOfViewSlider = uislider(app.Panel_5);
            app.visualFieldFieldOfViewSlider.ValueChangedFcn = createCallbackFcn(app, @visualFieldFieldOfViewSliderValueChanged, true);
            app.visualFieldFieldOfViewSlider.ValueChangingFcn = createCallbackFcn(app, @visualFieldFieldOfViewSliderValueChanging, true);
            app.visualFieldView = uiaxes(app.visualFieldTab);
            xlabel(app.visualFieldView, 'X')
            zlabel(app.visualFieldView, 'Z')
            app.stimulusTab = uitab(app.TabGroup2);
            app.stimulusTab.Title = 'stimulus';
            app.Panel_7 = uipanel(app.stimulusTab);
            app.stimulusdurationmsecSpinnerLabel = uilabel(app.Panel_7);
            app.stimulusdurationmsecSpinnerLabel.Text = 'stimulus duration (msec) ';
            app.stimulusDurationSpinner = uispinner(app.Panel_7);
            app.stimulusDurationSpinner.Step = 10;
            app.stimulusDurationSpinner.Limits = [1 1000];
            app.stimulusDurationSpinner.ValueChangedFcn = createCallbackFcn(app, @stimulusDurationChanged, true);
            app.stimulusDurationSpinner.Value = 100;
            app.meanluminancecdm2SpinnerLabel = uilabel(app.Panel_7);
            app.meanluminancecdm2SpinnerLabel.Text = 'mean luminance (cd/m2) ';
            app.stimulusMeanLuminanceSpinner = uispinner(app.Panel_7);
            app.stimulusMeanLuminanceSpinner.Step = 10;
            app.stimulusMeanLuminanceSpinner.Limits = [1 1000];
            app.stimulusMeanLuminanceSpinner.ValueChangedFcn = createCallbackFcn(app, @stimulusMeanLuminanceChanged, true);
            app.stimulusMeanLuminanceSpinner.Value = 100;
            app.temporalfrequencyHzSpinnerLabel = uilabel(app.Panel_7);
            app.temporalfrequencyHzSpinnerLabel.Text = 'temporal frequency (Hz) ';
            app.stimulusTemporalFrequencySpinner = uispinner(app.Panel_7);
            app.stimulusTemporalFrequencySpinner.Step = 10;
            app.stimulusTemporalFrequencySpinner.Limits = [1 1000];
            app.stimulusTemporalFrequencySpinner.ValueChangedFcn = createCallbackFcn(app, @stimulusTemporalFrequencyChanged, true);
            app.stimulusTemporalFrequencySpinner.Value = 100;
            app.orientationdegLabel = uilabel(app.Panel_7);
            app.orientationdegLabel.Text = 'orientation (deg) ';
            app.stimulusOrientationSpinner = uispinner(app.Panel_7);
            app.stimulusOrientationSpinner.Step = 10;
            app.stimulusOrientationSpinner.Limits = [1 1000];
            app.stimulusOrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @stimulusOrientationChanged, true);
            app.stimulusOrientationSpinner.Value = 100;
            app.presentationmodeLabel = uilabel(app.Panel_7);
            app.presentationmodeLabel.Text = 'presentation mode ';
            app.stimulusPresentationModeDropDown = uidropdown(app.Panel_7);
            app.stimulusPresentationModeDropDown.Items = {'flashed', 'drifted', 'counterphased'};
            app.stimulusPresentationModeDropDown.ValueChangedFcn = createCallbackFcn(app, @stimulusPresentationModeChanged, true);
            app.stimulusPresentationModeDropDown.Value = 'flashed';
            app.spatialenvelopeLabel = uilabel(app.Panel_7);
            app.spatialenvelopeLabel.Text = 'spatial envelope ';
            app.stimulusSpatialEnvelopeDropDown = uidropdown(app.Panel_7);
            app.stimulusSpatialEnvelopeDropDown.Items = {'disk', 'rect', 'smooth'};
            app.stimulusSpatialEnvelopeDropDown.ValueChangedFcn = createCallbackFcn(app, @stimulusSpatialEnvelopeChanged, true);
            app.stimulusSpatialEnvelopeDropDown.Value = 'rect';
            app.spatialfrequencycdegSpinnerLabel = uilabel(app.Panel_7);
            app.spatialfrequencycdegSpinnerLabel.Text = 'spatial frequency (c/deg) ';
            app.stimulusSpatialFrequencySpinner = uispinner(app.Panel_7);
            app.stimulusSpatialFrequencySpinner.Step = 10;
            app.stimulusSpatialFrequencySpinner.Limits = [1 1000];
            app.stimulusSpatialFrequencySpinner.ValueChangedFcn = createCallbackFcn(app, @stimulusSpatialFrequencyChanged, true);
            app.stimulusSpatialFrequencySpinner.Value = 100;
            app.sizedegSpinnerLabel = uilabel(app.Panel_7);
            app.sizedegSpinnerLabel.Text = 'size (deg) ';
            app.stimulusSizeSpinner = uispinner(app.Panel_7);
            app.stimulusSizeSpinner.Step = 10;
            app.stimulusSizeSpinner.Limits = [1 1000];
            app.stimulusSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @stimulusSizeChanged, true);
            app.stimulusSizeSpinner.Value = 100;
            app.resolutionpixelsSpinnerLabel = uilabel(app.Panel_7);
            app.resolutionpixelsSpinnerLabel.Text = 'resolution (pixels) ';
            app.stimulusResolutionSpinner = uispinner(app.Panel_7);
            app.stimulusResolutionSpinner.Step = 10;
            app.stimulusResolutionSpinner.Limits = [1 1000];
            app.stimulusResolutionSpinner.ValueChangedFcn = createCallbackFcn(app, @stimulusResolutionChanged, true);
            app.stimulusResolutionSpinner.Value = 100;
            app.xSpinnerLabel = uilabel(app.Panel_7);
            app.xSpinnerLabel.Text = 'x';
            app.stimulusSpatialPositionXSpinner = uispinner(app.Panel_7);
            app.stimulusSpatialPositionXSpinner.Step = 10;
            app.stimulusSpatialPositionXSpinner.Limits = [1 1000];
            app.stimulusSpatialPositionXSpinner.ValueChangedFcn = createCallbackFcn(app, @stimulusSpatialPositionXChanged, true);
            app.stimulusSpatialPositionXSpinner.Value = 100;
            app.positiondegLabel = uilabel(app.Panel_7);
            app.positiondegLabel.Text = 'position (deg) ';
            app.ySpinnerLabel = uilabel(app.Panel_7);
            app.ySpinnerLabel.Text = 'y';
            app.stimulusSpatialPositionYSpinner = uispinner(app.Panel_7);
            app.stimulusSpatialPositionYSpinner.Step = 10;
            app.stimulusSpatialPositionYSpinner.Limits = [1 1000];
            app.stimulusSpatialPositionYSpinner.ValueChangedFcn = createCallbackFcn(app, @stimulusSpatialPositionYChanged, true);
            app.stimulusSpatialPositionYSpinner.Value = 100;
            app.stimulusMosaicCenteredCheckBox = uicheckbox(app.Panel_7);
            app.stimulusMosaicCenteredCheckBox.ValueChangedFcn = createCallbackFcn(app, @stimulusMosaicCenteredChanged, true);
            app.stimulusMosaicCenteredCheckBox.Tooltip = {'Enabling this ensures that the stimulus is always centered on the cone mosaic'};
            app.stimulusMosaicCenteredCheckBox.Text = 'mosaic-centered';
            app.LconecontrastSpinnerLabel = uilabel(app.Panel_7);
            app.LconecontrastSpinnerLabel.Text = 'L-cone contrast (%) ';
            app.stimulusLconeContrastSpinner = uispinner(app.Panel_7);
            app.stimulusLconeContrastSpinner.Step = 10;
            app.stimulusLconeContrastSpinner.Limits = [1 1000];
            app.stimulusLconeContrastSpinner.ValueChangedFcn = createCallbackFcn(app, @stimulusLconeContrastChanged, true);
            app.stimulusLconeContrastSpinner.Value = 100;
            app.MconecontrastSpinnerLabel = uilabel(app.Panel_7);
            app.MconecontrastSpinnerLabel.Text = 'M-cone contrast (%) ';
            app.stimulusMconeContrastSpinner = uispinner(app.Panel_7);
            app.stimulusMconeContrastSpinner.Step = 10;
            app.stimulusMconeContrastSpinner.Limits = [1 1000];
            app.stimulusMconeContrastSpinner.ValueChangedFcn = createCallbackFcn(app, @stimulusMconeContrastChanged, true);
            app.stimulusMconeContrastSpinner.Value = 100;
            app.SconecontrastSpinnerLabel = uilabel(app.Panel_7);
            app.SconecontrastSpinnerLabel.Text = 'S-cone contrast (%) ';
            app.stimulusSconeContrastSpinner = uispinner(app.Panel_7);
            app.stimulusSconeContrastSpinner.Step = 10;
            app.stimulusSconeContrastSpinner.Limits = [1 1000];
            app.stimulusSconeContrastSpinner.ValueChangedFcn = createCallbackFcn(app, @stimulusSconeContrastChanged, true);
            app.stimulusSconeContrastSpinner.Value = 100;
            app.wavelengthsupportminnmSpinnerLabel = uilabel(app.Panel_7);
            app.wavelengthsupportminnmSpinnerLabel.Text = 'wavelength support, min (nm) ';
            app.stimulusWavelengthSupportMinSpinner = uispinner(app.Panel_7);
            app.stimulusWavelengthSupportMinSpinner.Step = 10;
            app.stimulusWavelengthSupportMinSpinner.Limits = [1 1000];
            app.stimulusWavelengthSupportMinSpinner.ValueChangedFcn = createCallbackFcn(app, @stimulusWavelengthSupportMinChanged, true);
            app.stimulusWavelengthSupportMinSpinner.Value = 100;
            app.wavelengthsupportmaxnmSpinnerLabel = uilabel(app.Panel_7);
            app.wavelengthsupportmaxnmSpinnerLabel.Text = 'wavelength support, max (nm) ';
            app.stimulusWavelengthSupportMaxSpinner = uispinner(app.Panel_7);
            app.stimulusWavelengthSupportMaxSpinner.Step = 10;
            app.stimulusWavelengthSupportMaxSpinner.Limits = [1 1000];
            app.stimulusWavelengthSupportMaxSpinner.ValueChangedFcn = createCallbackFcn(app, @stimulusWavelengthSupportMaxChanged, true);
            app.stimulusWavelengthSupportMaxSpinner.Value = 100;
            app.wavelengthsupportstepsizenmSpinnerLabel = uilabel(app.Panel_7);
            app.wavelengthsupportstepsizenmSpinnerLabel.Text = 'wavelength support, step size (nm) ';
            app.stimulusWavelengthSupportStepSizeSpinner = uispinner(app.Panel_7);
            app.stimulusWavelengthSupportStepSizeSpinner.Step = 10;
            app.stimulusWavelengthSupportStepSizeSpinner.Limits = [1 1000];
            app.stimulusWavelengthSupportStepSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @stimulusWavelengthSupportStepSizeChanged, true);
            app.stimulusWavelengthSupportStepSizeSpinner.Value = 100;
            app.stimulusView = uiaxes(app.stimulusTab);
            zlabel(app.stimulusView, 'Z')
            app.stimulusView.XTick = [];
            app.stimulusView.YTick = [];
            app.performanceassessmentTab = uitab(app.TabGroup2);
            app.performanceassessmentTab.Title = 'performance assessment';
            app.Panel = uipanel(app.GridLayout);
            app.leftStatusField = uieditfield(app.Panel, 'text');
            app.leftStatusField.Editable = 'off';
            app.leftStatusField.Value = 'left status';
            app.Panel_2 = uipanel(app.GridLayout);
            app.rightStatusField = uieditfield(app.Panel_2, 'text');
            app.rightStatusField.Editable = 'off';
            app.rightStatusField.Value = 'right status';
            % Show the figure after all components are created
            app.mainView.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ISETBioCSFGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainView)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainView)
        end
    end
end